classdef Pranov_Asif_AppAssignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WhenNatureMeetsRadioWavesLabel  matlab.ui.control.Label
        SidesslitSpinner                matlab.ui.control.Spinner
        SidesslitSpinnerLabel           matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        ApertureDropDown                matlab.ui.control.DropDown
        ApertureDropDownLabel           matlab.ui.control.Label
        ThreeDPlot                      matlab.ui.control.UIAxes
        TwoDPlot                        matlab.ui.control.UIAxes
        PatternAxes                     matlab.ui.control.UIAxes
        ApertureAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aperture % Description
        numSides % Description
        apertureType % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ApertureDropDown, StartButton
        function StartButtonPushed(app, event)
            % Update UI based on the selected aperture type
           
            app.ApertureDropDownClicked();
            app.numSides = app.SidesslitSpinner.Value;
            if app.ApertureDropDown.Value=="HUMAN"
                a=imread("man2.jpg");
                imagesc(app.ApertureAxes,a);
                colormap(app.ApertureAxes,'hot');
                axis(app.ApertureAxes,'tight');
            elseif app.ApertureDropDown.Value=="NIT W"
                b=imread("NITWlogo.jpg");
                imagesc(app.ApertureAxes,b);
                colormap(app.ApertureAxes,'hot');
                axis(app.ApertureAxes,'tight');

           else

               
           imagesc(app.ApertureAxes,app.aperture);
           colormap(app.ApertureAxes,'hot');
           axis(app.ApertureAxes,'tight');

           end
            u=fftshift(fft2(app.aperture));
            I=u.*conj(u);
            imagesc(app.PatternAxes,abs(u));
            colormap(app.PatternAxes,'hot');
            axis(app.PatternAxes,'tight');
            
            
            plot(app.TwoDPlot,I(:,50));
            axis(app.TwoDPlot,'tight');

            surf(app.ThreeDPlot, I );
            title(app.ThreeDPlot, '3D plot');
            colormap(app.ThreeDPlot, 'parula');
            shading(app.ThreeDPlot, 'flat');
            camlight(app.ThreeDPlot, 'headlight');
            axis(app.ThreeDPlot,'tight');


            app.ApertureDropDownClicked();
            app.SidesslitSpinnerValueChanged();

        end

        % Clicked callback: ApertureDropDown
        function ApertureDropDownClicked(app, event)


            app.apertureType = app.ApertureDropDown.Value;
          
           app.numSides = app.SidesslitSpinner.Value;
           if strcmp(app.ApertureDropDown.Value, 'Polygon')
               l=2;
               m=300;
               dx=l/m;
               x=-l/2:dx:l/2-dx;
               y=x;
               [x,y]=meshgrid(x,y);
               radius = 0.1;  
               polygon = nsidedpoly(app.numSides,'Radius',radius);
               app.aperture=inpolygon(x,y,polygon.Vertices(:,1),polygon.Vertices(:,2));
               set(app.SidesslitSpinner, 'Enable', 'on');
         
           
           elseif strcmp(app.ApertureDropDown.Value,'MultiSlit')
               % numSlits = app.SidesslitSpinner.Value; % Assuming you have a spinner for selecting the number of slits
               l=2;
               m=200;
               dx=l/m;
               x=-l/2:dx:l/2-dx;
               y=x;
               [x,y]=meshgrid(x,y);
                slitWidth = 0.02; % Adjust slit width as needed
                slitSpacing = 0.099; % Adjust slit spacing as needed
    
                 % Create an empty aperture matrix
                app.aperture = zeros(size(x));
    
                for i = 1:app.numSides
                    % Calculate the x-coordinate limits for the current slit
                    slitCenter = (i - (app.numSides + 1) / 2) * slitSpacing;
                    slitXMin = slitCenter - slitWidth / 2;
                    slitXMax = slitCenter + slitWidth / 2;

                    % Define the aperture for the current slit
                    slitAperture = (x >= slitXMin) & (x <= slitXMax);

                    % Add the aperture for the current slit to the total aperture
                    app.aperture = app.aperture + slitAperture;
                   
                    set(app.SidesslitSpinner,'Enable','on');


                end

           elseif strcmp(app.ApertureDropDown.Value,'OSTRICH EGG')
               x1 = linspace(-5, 5, 1000);
               y1 = linspace(-3, 3, 1000);
               [X1, Y1] = meshgrid(x1, y1);

               % Define the equation of the ellipse
               equation1 = X1.^2/16 + Y1.^2 .* (1 + 0.1 * X1)/4 - 1;
               binaryImage1 = equation1 <= 0;
               %imagesc(binaryImage1)
               %title('Ostrich egg (Oval)');
               L=24;
               M=500;
               dx=L/M;
               x=-L/2:dx:L/2;
               y=x;
               % Create a grid of coordinates
               [X, Y] = meshgrid(x, y);

               equation = X.^2/16 + Y.^2 .* (1 + 0.1 * X)/4 - 0.1;

               % Create binary image based on contour

               app.aperture = equation <= 0;
               set(app.SidesslitSpinner,'Enable','off');

           elseif strcmp(app.ApertureDropDown.Value,'NIT W')
               zeroMatrixSize = 3000;
              
               I=imread('NITW.jpg');
              
               I=imresize(I,[1024 1024]);
               % I(512,:)=0;
               % I(:,512)=0;
               % I=I>20
               

               % Define the size of the sub-matrix
               subMatrixSize = 1024; % For example, if you want a 10x10 sub-matrix

               % Create the zero matrix
               zeroMatrix = zeros(zeroMatrixSize);


               % Define the indices for placing the sub-matrix in the middle of the zero matrix
               startRow = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
               endRow = startRow + subMatrixSize - 1;
               startCol = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
               endCol = startCol + subMatrixSize - 1;


               % Create the sub-matrix (assuming it's all ones for demonstration)
               % subMatrix = ones(subMatrixSize);

               % Place the sub-matrix in the middle of the zero matrix
               zeroMatrix(startRow:endRow, startCol:endCol)=I;
               app.aperture=zeroMatrix;
               set(app.SidesslitSpinner,'Enable','off');


                elseif strcmp(app.ApertureDropDown.Value,'HUMAN')
                   zeroMatrixSize = 1000;
                  
                   I=imread('man.jpg');
                   I=imresize(I,[512 512]);
                   I(512,:)=0;
                   I(:,512)=0;
                   I=I>20;
                   %imagesc(I)

                   % Define the size of the sub-matrix
                   subMatrixSize = 512; % For example, if you want a 10x10 sub-matrix

                   % Create the zero matrix
                   zeroMatrix = zeros(zeroMatrixSize);


                   % Define the indices for placing the sub-matrix in the middle of the zero matrix
                   startRow = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
                   endRow = startRow + subMatrixSize - 1;
                   startCol = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
                   endCol = startCol + subMatrixSize - 1;


                   % Create the sub-matrix (assuming it's all ones for demonstration)
                   % subMatrix = ones(subMatrixSize);

                   % Place the sub-matrix in the middle of the zero matrix
                   zeroMatrix(startRow:endRow, startCol:endCol) = I;
                   app.aperture=zeroMatrix;
                   set(app.SidesslitSpinner,'Enable','off');

           
               elseif strcmp(app.ApertureDropDown.Value,'DOG')
                   zeroMatrixSize = 1000;
                  
                   I=imread('dod.jpg');
                   I=imresize(I,[512 512]);
                   I(512,:)=0;
                   I(:,512)=0;
                   I=I>20;
                   %imagesc(I)

                   % Define the size of the sub-matrix
                   subMatrixSize = 512; % For example, if you want a 10x10 sub-matrix

                   % Create the zero matrix
                   zeroMatrix = zeros(zeroMatrixSize);


                   % Define the indices for placing the sub-matrix in the middle of the zero matrix
                   startRow = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
                   endRow = startRow + subMatrixSize - 1;
                   startCol = floor((zeroMatrixSize - subMatrixSize) / 2) + 1;
                   endCol = startCol + subMatrixSize - 1;


                   % Create the sub-matrix (assuming it's all ones for demonstration)
                   % subMatrix = ones(subMatrixSize);

                   % Place the sub-matrix in the middle of the zero matrix
                   zeroMatrix(startRow:endRow, startCol:endCol) = I;
                   app.aperture=zeroMatrix;
                   set(app.SidesslitSpinner,'Enable','off');

           end
        end

        % Value changed function: SidesslitSpinner
        function SidesslitSpinnerValueChanged(app, event)
            app.apertureType
            app.numSides = app.SidesslitSpinner.Value;
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ApertureAxes = uiaxes(app.UIFigure);
            title(app.ApertureAxes, 'APERTURE')
            app.ApertureAxes.XTick = [];
            app.ApertureAxes.YTick = [];
            app.PatternAxes = uiaxes(app.UIFigure);
            title(app.PatternAxes, 'Diffraction Pattern')
            app.PatternAxes.XTick = [];
            app.PatternAxes.YTick = [];
            app.TwoDPlot = uiaxes(app.UIFigure);
            title(app.TwoDPlot, '2D Plot')
            app.TwoDPlot.XTick = [];
            app.TwoDPlot.YTick = [];
            app.ThreeDPlot = uiaxes(app.UIFigure);
            title(app.ThreeDPlot, '3D Plot')
            app.ThreeDPlot.XTick = [];
            app.ThreeDPlot.YTick = [];
            app.ApertureDropDownLabel = uilabel(app.UIFigure);
            app.ApertureDropDownLabel.Text = 'Aperture';
            app.ApertureDropDown = uidropdown(app.UIFigure);
            app.ApertureDropDown.Items = {'Polygon', 'MultiSlit', 'HUMAN', 'OSTRICH EGG', 'DOG', 'NIT W'};
            app.ApertureDropDown.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.ApertureDropDown.ClickedFcn = createCallbackFcn(app, @ApertureDropDownClicked, true);
            app.ApertureDropDown.Value = 'Polygon';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SidesslitSpinnerLabel = uilabel(app.UIFigure);
            app.SidesslitSpinnerLabel.Text = 'Sides/slit';
            app.SidesslitSpinner = uispinner(app.UIFigure);
            app.SidesslitSpinner.Limits = [3 10];
            app.SidesslitSpinner.RoundFractionalValues = 'on';
            app.SidesslitSpinner.ValueChangedFcn = createCallbackFcn(app, @SidesslitSpinnerValueChanged, true);
            app.SidesslitSpinner.Value = 3;
            app.WhenNatureMeetsRadioWavesLabel = uilabel(app.UIFigure);
            app.WhenNatureMeetsRadioWavesLabel.Text = '                        When Nature Meets Radio Waves';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pranov_Asif_AppAssignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end